classdef GetParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        HighpassFourierfilterSliderLabel  matlab.ui.control.Label
        HighpassFourierFilterSlider  matlab.ui.control.Slider
        ThresholdingLabel            matlab.ui.control.Label
        ThresholdingSlider           matlab.ui.control.Slider
        ShowButtonGroup              matlab.ui.container.ButtonGroup
        OriginalImageButton          matlab.ui.control.ToggleButton
        DetectedCellsButton          matlab.ui.control.ToggleButton
        OverlaidButton               matlab.ui.control.ToggleButton
        MorphologicalopeningLabel    matlab.ui.control.Label
        MorphologicalOpening         matlab.ui.control.Slider
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %% Initializing the app
        global channel croppingMask filename
        global selectedButton
        
        % Loading the image
        addpath('/users/Vardges/Documents/MATLAB/bfmatlab_552')
        [~, ~, channel, address]=readCzi();
        [filepath, filename, ~]=fileparts(address);
        % Cropping out the relevant part
        figure
        currentImage=cat(3, zeros(size(channel)), channel, zeros(size(channel)));
        imshow(currentImage);
        croppingMask=uint16(roipoly());
        close gcf 
        
        % Showing the cropped image 
        currentImage=cat(3, zeros(size(channel)), channel.*croppingMask, zeros(size(channel)));
        image(app.UIAxes, currentImage)
        selectedButton=app.OriginalImageButton;      
        end

        % Value changed function: HighpassFourierFilterSlider
        function HighpassFourierFilterSliderValueChanged(app, event)
        %% Fourier filtering the image
        global channel processedFourier croppingMask currentFrame 
        global fourierRadius selectedButton
        
        % User input of the radius of the High-pass Fourier filter
        fourierRadius = round(app.HighpassFourierFilterSlider.Value);
        
        % Executing the filter
        highpass=zeros(size(channel));
        midpoint=round(size(channel)/2);
        [y,x]=size(channel);
        [y,x]=meshgrid(1:x,1:y);
        highpass= (sqrt((x-midpoint(1)).^2+(y-midpoint(2)).^2))>fourierRadius;
        
        processedFourier=fft2(channel);
        fourierShifted=fftshift(processedFourier);
        fourierShifted=fourierShifted.*highpass;
        processedFourier=(ifft2(ifftshift(fourierShifted)));
        processedFourier=uint16(real(processedFourier));
       
        % Displaying the image based on user input through buttons
        currentFrame=processedFourier.*croppingMask;
        switch selectedButton
                case app.OriginalImageButton
                    currentImage = cat(3,zeros(size(channel)),channel,zeros(size(channel)));
                    image(app.UIAxes, currentImage)
                case app.DetectedCellsButton
                    currentImage = cat(3,zeros(size(currentFrame)),3*currentFrame,zeros(size(currentFrame)));
                    image(app.UIAxes, currentImage)
                case app.OverlaidButton
                   currentImage=cat(3,zeros(size(channel)),channel,3*currentFrame);
                   image(app.UIAxes, currentImage)
        end
        
        % Adding a title to the figure   
        title(app.UIAxes,sprintf('F=%d', fourierRadius), 'FontSize', 18)  
        end

        % Value changed function: MorphologicalOpening
        function MorphologicalOpeningValueChanged(app, event)
        %% Morphological opening 
        global channel processedNoise processedFourier croppingMask currentFrame
        global diskRadius fourierRadius selectedButton 
        
        
        % Morphological opening to clear cell processes
        diskRadius = round(app.MorphologicalOpening.Value);
        window=strel('disk', diskRadius, 0);
        processedNoise= imopen(processedFourier, window); 
       
        % Displaying the image based on user input through buttons
        currentFrame=uint16(processedNoise).*uint16(croppingMask);
        switch selectedButton
                case app.OriginalImageButton
                    currentImage = cat(3,zeros(size(channel)),channel,zeros(size(channel)));
                    image(app.UIAxes, currentImage)
                case app.DetectedCellsButton
                    currentImage = cat(3,zeros(size(currentFrame)),3*currentFrame,zeros(size(currentFrame)));
                    image(app.UIAxes, currentImage)
                case app.OverlaidButton
                   currentImage=cat(3,zeros(size(channel)),channel,3*currentFrame);
                   image(app.UIAxes, currentImage)
        end
        
        title(app.UIAxes,sprintf('F=%d, R=%d', fourierRadius, diskRadius), 'FontSize', 18)   
        end

        % Selection changed function: ShowButtonGroup
        function ShowButtonGroupSelectionChanged(app, event)
        %% Select buttons to view the image of interest
        global channel currentFrame
        global selectedButton
        
        % User input of the selected button
        selectedButton = app.ShowButtonGroup.SelectedObject;
        
        % Displaying either the original image or the processed image or the two of them overlaid
        switch selectedButton
                case app.OriginalImageButton
                    currentImage = cat(3,zeros(size(channel)),channel,zeros(size(channel)));
                    image(app.UIAxes, currentImage) 
                case app.DetectedCellsButton
                    currentImage = cat(3,zeros(size(currentFrame)),3*currentFrame,zeros(size(currentFrame)));
                    image(app.UIAxes, currentImage)
                case app.OverlaidButton
                    currentImage=cat(3, 3*currentFrame, channel, zeros(size(channel)));
                    image(app.UIAxes, currentImage)
        end
        end

        % Value changed function: ThresholdingSlider
        function ThresholdingSliderValueChanged(app, event)
            %% Applying a threshold to segment the image
            global channel processedNoise croppingMask currentFrame
            global diskRadius fourierRadius thresh selectedButton
            
            % User input of the threshold 
            thresh=round(app.ThresholdingSlider.Value);
            
            % Applying the threshold to the image
            processedThresh= (processedNoise>=thresh*256);
            
            % Representing outlines of detected cells
            outlinesGreen=bwperim(processedThresh, 8);
            outlinesGreen=bwmorph(outlinesGreen, 'thicken', 1);
            outlinesGreen=im2uint16(outlinesGreen);
            currentFrame=im2uint16(outlinesGreen).*croppingMask;
            
            % Displaying the current state of the processed image
            switch selectedButton
                    case app.OriginalImageButton
                        currentImage = cat(3,zeros(size(channel)),channel,zeros(size(channel)));
                        image(app.UIAxes, currentImage)
                    case app.DetectedCellsButton
                        currentImage = cat(3,zeros(size(currentFrame)),3*currentFrame,zeros(size(currentFrame)));
                        image(app.UIAxes, currentImage)
                    case app.OverlaidButton
                        currentImage=cat(3,3*currentFrame, channel, zeros(size(channel)));
                        image(app.UIAxes, currentImage)
            end
            
            % Counting the number of cells on the current image
            cellLocsGreen= bwconncomp(processedThresh>0);
            nCells=cellLocsGreen.NumObjects;
            
            title(app.UIAxes,sprintf('F=%d, R=%d, T=%d, N_{cells}=%d' , fourierRadius, diskRadius, thresh, nCells), 'FontSize', 18)   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        % Save parameters upon closing
        global fourierRadius diskRadius thresh
        
        save('parameters.mat', 'fourierRadius', 'diskRadius', 'thresh', '-append');
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {''; ''};
            app.HighpassFourierfilterSliderLabel = uilabel(app.UIFigure);
            app.HighpassFourierfilterSliderLabel.Text = 'High-pass Fourier filter';
            app.HighpassFourierFilterSlider = uislider(app.UIFigure);
            app.HighpassFourierFilterSlider.Limits = [1 20];
            app.HighpassFourierFilterSlider.ValueChangedFcn = createCallbackFcn(app, @HighpassFourierFilterSliderValueChanged, true);
            app.HighpassFourierFilterSlider.Value = 3;
            app.ThresholdingLabel = uilabel(app.UIFigure);
            app.ThresholdingLabel.Text = 'Thresholding';
            app.ThresholdingSlider = uislider(app.UIFigure);
            app.ThresholdingSlider.Limits = [0 30];
            app.ThresholdingSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdingSliderValueChanged, true);
            app.ThresholdingSlider.Value = 15;
            app.ShowButtonGroup = uibuttongroup(app.UIFigure);
            app.ShowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowButtonGroupSelectionChanged, true);
            app.ShowButtonGroup.Title = 'Show...';
            app.OriginalImageButton = uitogglebutton(app.ShowButtonGroup);
            app.OriginalImageButton.Text = 'Original Image';
            app.OriginalImageButton.Value = true;
            app.DetectedCellsButton = uitogglebutton(app.ShowButtonGroup);
            app.DetectedCellsButton.Text = 'Detected Cells';
            app.OverlaidButton = uitogglebutton(app.ShowButtonGroup);
            app.OverlaidButton.Text = 'Overlaid';
            app.MorphologicalopeningLabel = uilabel(app.UIFigure);
            app.MorphologicalopeningLabel.Text = 'Morphological opening';
            app.MorphologicalOpening = uislider(app.UIFigure);
            app.MorphologicalOpening.Limits = [1 15];
            app.MorphologicalOpening.ValueChangedFcn = createCallbackFcn(app, @MorphologicalOpeningValueChanged, true);
            app.MorphologicalOpening.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GetParameters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end